classdef bingoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        Image_2           matlab.ui.control.Image
        Label             matlab.ui.control.Label
        CheckButton       matlab.ui.control.Button
        Image             matlab.ui.control.Image
        GenerateButton    matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
        BINGOLabel        matlab.ui.control.Label
        BINGO2022Label    matlab.ui.control.Label
        JULEFROKOSTLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        hist % Description
        A % Description
        nIt % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.A = readlines('bingo.txt');     % Load entries

            nEntries = size(app.A,1);

            rng(4)
            app.hist = randperm(nEntries);

            % Iterations
            app.nIt = 0;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.nIt = app.nIt + 1;
            app.Label.Text = string(min([app.nIt size(app.A,1)]));

            if app.nIt <= size(app.A,1)
                app.BINGOLabel.Text = app.A(app.hist(app.nIt));
            else
                app.BINGOLabel.Text = "BINGO!";
            end
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            historic(app.hist,app.nIt,app.A);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {682, 682};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images.jpeg');
            app.CheckButton = uibutton(app.LeftPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '0';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Black_RGB_000.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.JULEFROKOSTLabel = uilabel(app.RightPanel);
            app.JULEFROKOSTLabel.Text = 'JULEFROKOST';
            app.BINGO2022Label = uilabel(app.RightPanel);
            app.BINGO2022Label.Text = 'BINGO 2022';
            app.BINGOLabel = uilabel(app.RightPanel);
            app.BINGOLabel.Interpreter = 'latex';
            app.BINGOLabel.Text = 'BINGO!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bingoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end